classdef ALP_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        uipanel1                     matlab.ui.container.Panel
        text25                       matlab.ui.control.Label
        text27                       matlab.ui.control.Label
        text29                       matlab.ui.control.Label
        text31                       matlab.ui.control.Label
        text33                       matlab.ui.control.Label
        text35                       matlab.ui.control.Label
        sTextAreaLabel               matlab.ui.control.Label
        sTextArea                    matlab.ui.control.TextArea
        sTextArea_2Label             matlab.ui.control.Label
        sTextArea_2                  matlab.ui.control.TextArea
        sTextArea_3Label             matlab.ui.control.Label
        sTextArea_3                  matlab.ui.control.TextArea
        text25_2                     matlab.ui.control.Label
        packetsTextAreaLabel         matlab.ui.control.Label
        packetsTextArea              matlab.ui.control.TextArea
        packetsLabel                 matlab.ui.control.Label
        packetsTextArea_2            matlab.ui.control.TextArea
        sTextArea_6                  matlab.ui.control.TextArea
        sTextArea_7                  matlab.ui.control.TextArea
        commands_menu                matlab.ui.container.Panel
        Receive_Data_button          matlab.ui.control.Button
        ABORT_button                 matlab.ui.control.Button
        Deploy_Chutes_button         matlab.ui.control.Button
        uipanel6                     matlab.ui.container.Panel
        ChutesDeployedConfirmedLampLabel  matlab.ui.control.Label
        ChutesDeployedConfirmedLamp  matlab.ui.control.Lamp
        AbortConfirmedLampLabel      matlab.ui.control.Label
        AbortConfirmedLamp           matlab.ui.control.Lamp
        ReceivingDataLampLabel       matlab.ui.control.Label
        ReceivingDataLamp            matlab.ui.control.Lamp
        uipanel2                     matlab.ui.container.Panel
        text37                       matlab.ui.control.Label
        text39                       matlab.ui.control.Label
        text41                       matlab.ui.control.Label
        text43                       matlab.ui.control.Label
        text45                       matlab.ui.control.Label
        CTextAreaLabel               matlab.ui.control.Label
        CTextArea                    matlab.ui.control.TextArea
        CTextArea_2Label             matlab.ui.control.Label
        CTextArea_2                  matlab.ui.control.TextArea
        CTextArea_3Label             matlab.ui.control.Label
        CTextArea_3                  matlab.ui.control.TextArea
        VTextAreaLabel               matlab.ui.control.Label
        VTextArea                    matlab.ui.control.TextArea
        ATextAreaLabel               matlab.ui.control.Label
        ATextArea                    matlab.ui.control.TextArea
        uipanel7                     matlab.ui.container.Panel
        end_receive                  matlab.ui.control.Button
        end_program                  matlab.ui.control.Button
        InitializeGUIButton          matlab.ui.control.Button
        are_you_sure_menu            matlab.ui.container.Panel
        NO_button                    matlab.ui.control.Button
        YES_button                   matlab.ui.control.Button
        uipanel1_2                   matlab.ui.container.Panel
        AltitudekmEditFieldLabel     matlab.ui.control.Label
        AltitudekmEditField          matlab.ui.control.NumericEditField
        PressurekPaEditFieldLabel    matlab.ui.control.Label
        PressurekPaEditField         matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel   matlab.ui.control.Label
        TemperatureCEditField        matlab.ui.control.NumericEditField
        AccelerationxEditFieldLabel  matlab.ui.control.Label
        AccelerationxEditField       matlab.ui.control.NumericEditField
        AccelerationyEditFieldLabel  matlab.ui.control.Label
        AccelerationyEditField       matlab.ui.control.NumericEditField
        AccelerationzEditFieldLabel  matlab.ui.control.Label
        AccelerationzEditField       matlab.ui.control.NumericEditField
        TiltxEditFieldLabel          matlab.ui.control.Label
        TiltxEditField               matlab.ui.control.NumericEditField
        TiltyEditFieldLabel          matlab.ui.control.Label
        TiltyEditField               matlab.ui.control.NumericEditField
        TiltzEditFieldLabel          matlab.ui.control.Label
        TiltzEditField               matlab.ui.control.NumericEditField
        GPSLongitudeEditFieldLabel   matlab.ui.control.Label
        GPSLongitudeEditField        matlab.ui.control.NumericEditField
        GPSLatitudeEditFieldLabel    matlab.ui.control.Label
        GPSLatitudeEditField         matlab.ui.control.NumericEditField
        GPSAltitudeEditFieldLabel    matlab.ui.control.Label
        GPSAltitudeEditField         matlab.ui.control.NumericEditField
        uipanel6_2                   matlab.ui.container.Panel
        RocketFiredLampLabel         matlab.ui.control.Label
        RocketFiredLamp              matlab.ui.control.Lamp
        AbortMissionLampLabel        matlab.ui.control.Label
        AbortMissionLamp             matlab.ui.control.Lamp
        BalloonAscendingLampLabel    matlab.ui.control.Label
        BalloonAscendingLamp         matlab.ui.control.Lamp
    end

    
    methods (Access = private)
        function Master_update(app, hObject, eventdata, hfigure)
            handles = guidata(hfigure);
            ALP_readinserial(receiveData);
            ALP_Telemetry_Update(handles);
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ALP_OpeningFcn(app)
            set(app.AltitudekmGauge, 'ScaleColors', {'cyan', 'blue'},...
                               'ScaleColorLimits',[20 100; 100 120]);
            set(app.TemperatureCGauge, 'ScaleColors', {'yellow', 'red'},...
                               'ScaleColorLimits',[100 125; 125 150]);
            set(app.AltitudekmGaugeLabel, 'Visible', 'Off')
        end

        % Button pushed function: ABORT_button
        function ABORT_button_Callback(app, event)
            % hObject    handle to ABORT_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global abort
            abort = 1;
            set(app.are_you_sure_menu,'Visible','On')
            set(app.YES_button,'Visible','On')
            set(app.NO_button,'Visible','On')
        end

        % Button pushed function: Deploy_Chutes_button
        function Deploy_Chutes_button_Callback(app, event)
            % hObject    handle to Deploy_Chutes_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global deploy_chutes
            deploy_chutes = 1;
            set(app.are_you_sure_menu,'Visible','On')
            set(app.YES_button,'Visible','On')
            set(app.NO_button,'Visible','On')
        end

        % Button pushed function: NO_button
        function NO_button_Callback(app, event)
            % hObject    handle to NO_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global deploy_chutes
            global abort
            global stop_reading
            global end_program
            deploy_chutes = 0;
            abort = 0;
            stop_reading = 0;
            end_program = 0;
            set(app.YES_button,'Visible','Off')
            set(app.NO_button,'Visible','Off')
            set(app.are_you_sure_menu,'Visible','Off')
        end

        % Button pushed function: Receive_Data_button
        function Receive_Data_button_Callback(app, event)
            % ALP_readinserial(1)           Uncomment this to read serial
            
            set(app.ReceivingDataLamp, 'Color', 'g')
            
            
            % GPS Time
            app.sTextArea_2.Value = '5'
            
            % Packet Search Time
            app.sTextArea_3.Value = '5'
            
            % Batt Temp
            app.CTextArea.Value = '100'
            
            % Motor Temp
            app.CTextArea_2.Value = '100'
            
            % Board Temp
            app.CTextArea_3.Value = '100'
            
            % Voltage
            app.VTextArea.Value = '10'
            
            % Current
            app.ATextArea.Value = '0.500'
            
            % Altitude Gauge
            app.AltitudekmGauge.Value = 20
            
            % Altitude Field
            app.AltitudekmEditField.Value = 20
            
            % Pressure Gauge
            app.PressurekPaGauge.Value = 100
            
            % Pressure Field
            app.PressurekPaEditField.Value = 100
            
            % Temperature Gauge
            app.TemperatureCGauge.Value = 100
            
            % Temperature Field
            app.TemperatureCEditField.Value = 100
          
           
        end

        % Button pushed function: YES_button
        function YES_button_Callback(app, event)
            % hObject    handle to YES_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global deploy_chutes
            global abort
            global stop_reading
            global end_program
            if deploy_chutes == 1
                set(app.ChutesDeployedConfirmedLamp, 'Color','g')
                %ALP_sendcommands(1,0)
                deploy_chutes = 0
            elseif abort == 1
                set(app.AbortConfirmedLamp, 'Color','g')
                %ALP_sendcommands(0,1)
                abort = 0
            elseif stop_reading == 1
                set(app.ReceivingDataLamp, 'Color','r')
                %ALP_readinserial(0)
                stop_reading = 0
            elseif end_program == 1
                exit
            else ALP_sendcommands(0,0), ALP_readinserial(1)
            end
            
            
            set(app.YES_button,'Visible','Off')
            set(app.NO_button,'Visible','Off')
            set(app.are_you_sure_menu,'Visible','Off')
        end

        % Button pushed function: end_program
        function end_program_Callback(app, event)
            % hObject    handle to end_program (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global end_program
            end_program = 1;
            set(app.are_you_sure_menu,'Visible','On')
            set(app.YES_button,'Visible','On')
            set(app.NO_button,'Visible','On')
        end

        % Button pushed function: end_receive
        function end_receive_Callback(app, event)
            % hObject    handle to end_receive (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global stop_reading
            stop_reading = 1;
            set(app.are_you_sure_menu,'Visible','On')
            set(app.YES_button,'Visible','On')
            set(app.NO_button,'Visible','On')
        end

        % Button pushed function: InitializeGUIButton
        function InitializeGUIButtonPushed(app, event)
            MissionTime = tic;
            run = 1;
            minutes = 0;
            hours = 0;
            while run == 1
                
                seconds = round(toc(MissionTime),0)
                
                if mod(seconds,60) == 0 && seconds > 0
                    seconds = seconds - 60;
                    minutes = minutes + 1;
                end
                
                if mod(minutes,60) == 0 && minutes > 0
                    minutes = minutes - 60;
                    hours = hours + 1
                end
                
                secondsDisplay = seconds - (minutes) * 60
                
                if secondsDisplay == -60
                    secondsDisplay = 0
                end
                
                
                app.sTextArea.Value = num2str(secondsDisplay)
                app.sTextArea_2.Value = num2str(minutes)
                pause(1)
            end   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GS';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Software Status';
            app.text25 = uilabel(app.uipanel1);
            app.text25.Text = 'GPS Time';
            app.text27 = uilabel(app.uipanel1);
            app.text27.Text = 'Packet Count';
            app.text29 = uilabel(app.uipanel1);
            app.text29.Text = {'Previous Packet'; 'Count'};
            app.text31 = uilabel(app.uipanel1);
            app.text31.Text = {'Packet Search'; 'Time'};
            app.text33 = uilabel(app.uipanel1);
            app.text33.Text = 'Software State';
            app.text35 = uilabel(app.uipanel1);
            app.text35.Text = 'GPS Sat';
            app.sTextAreaLabel = uilabel(app.uipanel1);
            app.sTextAreaLabel.Text = '(s)';
            app.sTextArea = uitextarea(app.uipanel1);
            app.sTextArea_2Label = uilabel(app.uipanel1);
            app.sTextArea_2Label.Text = '(s)';
            app.sTextArea_2 = uitextarea(app.uipanel1);
            app.sTextArea_3Label = uilabel(app.uipanel1);
            app.sTextArea_3Label.Text = '(s)';
            app.sTextArea_3 = uitextarea(app.uipanel1);
            app.text25_2 = uilabel(app.uipanel1);
            app.text25_2.Text = 'Mission Time';
            app.packetsTextAreaLabel = uilabel(app.uipanel1);
            app.packetsTextAreaLabel.Text = '(packets)';
            app.packetsTextArea = uitextarea(app.uipanel1);
            app.packetsLabel = uilabel(app.uipanel1);
            app.packetsLabel.Text = '(packets)';
            app.packetsTextArea_2 = uitextarea(app.uipanel1);
            app.sTextArea_6 = uitextarea(app.uipanel1);
            app.sTextArea_7 = uitextarea(app.uipanel1);
            app.commands_menu = uipanel(app.figure1);
            app.commands_menu.Title = 'ALP Commands';
            app.Receive_Data_button = uibutton(app.commands_menu, 'push');
            app.Receive_Data_button.ButtonPushedFcn = createCallbackFcn(app, @Receive_Data_button_Callback, true);
            app.Receive_Data_button.Text = 'Receive Data';
            app.ABORT_button = uibutton(app.commands_menu, 'push');
            app.ABORT_button.ButtonPushedFcn = createCallbackFcn(app, @ABORT_button_Callback, true);
            app.ABORT_button.Text = 'ABORT';
            app.Deploy_Chutes_button = uibutton(app.commands_menu, 'push');
            app.Deploy_Chutes_button.ButtonPushedFcn = createCallbackFcn(app, @Deploy_Chutes_button_Callback, true);
            app.Deploy_Chutes_button.Text = 'Deploy Chutes';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Confirmation Status';
            app.ChutesDeployedConfirmedLampLabel = uilabel(app.uipanel6);
            app.ChutesDeployedConfirmedLampLabel.Text = 'Chutes Deployed Confirmed';
            app.ChutesDeployedConfirmedLamp = uilamp(app.uipanel6);
            app.AbortConfirmedLampLabel = uilabel(app.uipanel6);
            app.AbortConfirmedLampLabel.Text = 'Abort Confirmed';
            app.AbortConfirmedLamp = uilamp(app.uipanel6);
            app.ReceivingDataLampLabel = uilabel(app.uipanel6);
            app.ReceivingDataLampLabel.Text = 'Receiving Data';
            app.ReceivingDataLamp = uilamp(app.uipanel6);
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Hardware Status';
            app.text37 = uilabel(app.uipanel2);
            app.text37.Text = 'Batt Temp';
            app.text39 = uilabel(app.uipanel2);
            app.text39.Text = 'Motor Temp';
            app.text41 = uilabel(app.uipanel2);
            app.text41.Text = 'Board Temp';
            app.text43 = uilabel(app.uipanel2);
            app.text43.Text = 'Voltage';
            app.text45 = uilabel(app.uipanel2);
            app.text45.Text = 'Current';
            app.CTextAreaLabel = uilabel(app.uipanel2);
            app.CTextAreaLabel.Text = '(˚C)';
            app.CTextArea = uitextarea(app.uipanel2);
            app.CTextArea_2Label = uilabel(app.uipanel2);
            app.CTextArea_2Label.Text = '(˚C)';
            app.CTextArea_2 = uitextarea(app.uipanel2);
            app.CTextArea_3Label = uilabel(app.uipanel2);
            app.CTextArea_3Label.Text = '(˚C)';
            app.CTextArea_3 = uitextarea(app.uipanel2);
            app.VTextAreaLabel = uilabel(app.uipanel2);
            app.VTextAreaLabel.Text = '(V)';
            app.VTextArea = uitextarea(app.uipanel2);
            app.ATextAreaLabel = uilabel(app.uipanel2);
            app.ATextAreaLabel.Text = '(A)';
            app.ATextArea = uitextarea(app.uipanel2);
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = 'GUI Commands';
            app.end_receive = uibutton(app.uipanel7, 'push');
            app.end_receive.ButtonPushedFcn = createCallbackFcn(app, @end_receive_Callback, true);
            app.end_receive.Text = 'End Receive';
            app.end_program = uibutton(app.uipanel7, 'push');
            app.end_program.ButtonPushedFcn = createCallbackFcn(app, @end_program_Callback, true);
            app.end_program.Text = 'End Program';
            app.InitializeGUIButton = uibutton(app.uipanel7, 'push');
            app.InitializeGUIButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeGUIButtonPushed, true);
            app.InitializeGUIButton.Text = 'Initialize GUI';
            app.are_you_sure_menu = uipanel(app.figure1);
            app.are_you_sure_menu.Title = 'Confirm';
            app.NO_button = uibutton(app.are_you_sure_menu, 'push');
            app.NO_button.ButtonPushedFcn = createCallbackFcn(app, @NO_button_Callback, true);
            app.NO_button.Text = 'NO';
            app.YES_button = uibutton(app.are_you_sure_menu, 'push');
            app.YES_button.ButtonPushedFcn = createCallbackFcn(app, @YES_button_Callback, true);
            app.YES_button.Text = 'YES';
            app.uipanel1_2 = uipanel(app.figure1);
            app.uipanel1_2.Title = 'ALP Data';
            app.AltitudekmEditFieldLabel = uilabel(app.uipanel1_2);
            app.AltitudekmEditFieldLabel.Text = 'Altitude (km)';
            app.AltitudekmEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.PressurekPaEditFieldLabel = uilabel(app.uipanel1_2);
            app.PressurekPaEditFieldLabel.Text = 'Pressure (kPa)';
            app.PressurekPaEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.TemperatureCEditFieldLabel = uilabel(app.uipanel1_2);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (˚C)';
            app.TemperatureCEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.AccelerationxEditFieldLabel = uilabel(app.uipanel1_2);
            app.AccelerationxEditFieldLabel.Text = 'Acceleration x';
            app.AccelerationxEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.AccelerationyEditFieldLabel = uilabel(app.uipanel1_2);
            app.AccelerationyEditFieldLabel.Text = 'Acceleration y';
            app.AccelerationyEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.AccelerationzEditFieldLabel = uilabel(app.uipanel1_2);
            app.AccelerationzEditFieldLabel.Text = 'Acceleration z';
            app.AccelerationzEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.TiltxEditFieldLabel = uilabel(app.uipanel1_2);
            app.TiltxEditFieldLabel.Text = 'Tilt x';
            app.TiltxEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.TiltyEditFieldLabel = uilabel(app.uipanel1_2);
            app.TiltyEditFieldLabel.Text = 'Tilt y';
            app.TiltyEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.TiltzEditFieldLabel = uilabel(app.uipanel1_2);
            app.TiltzEditFieldLabel.Text = 'Tilt z';
            app.TiltzEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.GPSLongitudeEditFieldLabel = uilabel(app.uipanel1_2);
            app.GPSLongitudeEditFieldLabel.Text = 'GPS Longitude';
            app.GPSLongitudeEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.GPSLatitudeEditFieldLabel = uilabel(app.uipanel1_2);
            app.GPSLatitudeEditFieldLabel.Text = 'GPS Latitude';
            app.GPSLatitudeEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.GPSAltitudeEditFieldLabel = uilabel(app.uipanel1_2);
            app.GPSAltitudeEditFieldLabel.Text = 'GPS Altitude';
            app.GPSAltitudeEditField = uieditfield(app.uipanel1_2, 'numeric');
            app.uipanel6_2 = uipanel(app.figure1);
            app.uipanel6_2.Title = 'Flight Status';
            app.RocketFiredLampLabel = uilabel(app.uipanel6_2);
            app.RocketFiredLampLabel.Text = 'Rocket Fired';
            app.RocketFiredLamp = uilamp(app.uipanel6_2);
            app.AbortMissionLampLabel = uilabel(app.uipanel6_2);
            app.AbortMissionLampLabel.Text = 'Abort Mission';
            app.AbortMissionLamp = uilamp(app.uipanel6_2);
            app.BalloonAscendingLampLabel = uilabel(app.uipanel6_2);
            app.BalloonAscendingLampLabel.Text = 'Balloon Ascending';
            app.BalloonAscendingLamp = uilamp(app.uipanel6_2);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ALP_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @ALP_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end