classdef ALP_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        ALP_Commands                    matlab.ui.container.Panel
        Receive_Data_button             matlab.ui.control.Button
        ABORT_button                    matlab.ui.control.Button
        Deploy_Chutes_button            matlab.ui.control.Button
        Confirmation_Status             matlab.ui.container.Panel
        ChutesDeployedLampLabel         matlab.ui.control.Label
        ChutesDeployedLamp              matlab.ui.control.Lamp
        MissionAbortedLampLabel         matlab.ui.control.Label
        MissionAbortedLamp              matlab.ui.control.Lamp
        ReceivingDataLampLabel          matlab.ui.control.Label
        ReceivingDataLamp               matlab.ui.control.Lamp
        Hardware_Status                 matlab.ui.container.Panel
        BattTempCEditFieldLabel         matlab.ui.control.Label
        BattTempCEditField              matlab.ui.control.NumericEditField
        BoardTempCEditFieldLabel        matlab.ui.control.Label
        BoardTempCEditField             matlab.ui.control.NumericEditField
        VoltageVEditFieldLabel          matlab.ui.control.Label
        VoltageVEditField               matlab.ui.control.NumericEditField
        CurrentAEditFieldLabel          matlab.ui.control.Label
        CurrentAEditField               matlab.ui.control.NumericEditField
        MotorTempCEditFieldLabel        matlab.ui.control.Label
        MotorTempCEditField             matlab.ui.control.NumericEditField
        GUI_Commands                    matlab.ui.container.Panel
        end_receive                     matlab.ui.control.Button
        end_program                     matlab.ui.control.Button
        StartClockButton                matlab.ui.control.Button
        Are_You_Sure_Menu               matlab.ui.container.Panel
        NO_button                       matlab.ui.control.Button
        YES_button                      matlab.ui.control.Button
        ALP_Data                        matlab.ui.container.Panel
        AltitudekmEditFieldLabel        matlab.ui.control.Label
        AltitudekmEditField             matlab.ui.control.NumericEditField
        PressurekPaEditFieldLabel       matlab.ui.control.Label
        PressurekPaEditField            matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel      matlab.ui.control.Label
        TemperatureCEditField           matlab.ui.control.NumericEditField
        AccelerationxEditFieldLabel     matlab.ui.control.Label
        AccelerationxEditField          matlab.ui.control.NumericEditField
        AccelerationyEditFieldLabel     matlab.ui.control.Label
        AccelerationyEditField          matlab.ui.control.NumericEditField
        AccelerationzEditFieldLabel     matlab.ui.control.Label
        AccelerationzEditField          matlab.ui.control.NumericEditField
        TiltxEditFieldLabel             matlab.ui.control.Label
        TiltxEditField                  matlab.ui.control.NumericEditField
        TiltyEditFieldLabel             matlab.ui.control.Label
        TiltyEditField                  matlab.ui.control.NumericEditField
        TiltzEditFieldLabel             matlab.ui.control.Label
        TiltzEditField                  matlab.ui.control.NumericEditField
        GPSLongitudeEditFieldLabel      matlab.ui.control.Label
        GPSLongitudeEditField           matlab.ui.control.NumericEditField
        GPSLatitudeEditFieldLabel       matlab.ui.control.Label
        GPSLatitudeEditField            matlab.ui.control.NumericEditField
        GPSAltitudeEditFieldLabel       matlab.ui.control.Label
        GPSAltitudeEditField            matlab.ui.control.NumericEditField
        Flight_Status                   matlab.ui.container.Panel
        RocketIgnitedLampLabel          matlab.ui.control.Label
        RocketIgnitedLamp               matlab.ui.control.Lamp
        AbortSuggestedLampLabel         matlab.ui.control.Label
        AbortSuggestedLamp              matlab.ui.control.Lamp
        BalloonAscendingLampLabel       matlab.ui.control.Label
        BalloonAscendingLamp            matlab.ui.control.Lamp
        Software_Status                 matlab.ui.container.Panel
        PacketCountEditFieldLabel       matlab.ui.control.Label
        PacketCountEditField            matlab.ui.control.NumericEditField
        PacketSearchTimeEditFieldLabel  matlab.ui.control.Label
        PacketSearchTimeEditField       matlab.ui.control.NumericEditField
        PreviousPacketCountEditFieldLabel  matlab.ui.control.Label
        PreviousPacketCountEditField    matlab.ui.control.NumericEditField
        SoftwareStateEditFieldLabel     matlab.ui.control.Label
        SoftwareStateEditField          matlab.ui.control.NumericEditField
        GPSSatEditFieldLabel            matlab.ui.control.Label
        GPSSatEditField                 matlab.ui.control.NumericEditField
        Clock                           matlab.ui.container.Panel
        MET_Hours_to_Minutes            matlab.ui.control.EditField
        MET_Minutes_to_Seconds          matlab.ui.control.EditField
        MET_Seconds_Field               matlab.ui.control.EditField
        MET_Minutes_Field               matlab.ui.control.EditField
        MET_Hours_Field                 matlab.ui.control.EditField
        MET_Label                       matlab.ui.control.TextArea
        UTC_Hours_to_Minutes            matlab.ui.control.EditField
        UTC_Minutes_to_Seconds          matlab.ui.control.EditField
        UTC_Seconds_Field               matlab.ui.control.EditField
        UTC_Minutes_Field               matlab.ui.control.EditField
        UTC_Hours_Field                 matlab.ui.control.EditField
        UTC_Label_Field                 matlab.ui.control.TextArea
        SABER_Badge                     matlab.ui.control.Image
        SABER_Ground_Station_1          matlab.ui.control.TextArea
        ALP_Data_Graphs                 matlab.ui.container.TabGroup
        AltitudeTab                     matlab.ui.container.Tab
        Altitude_Graph                  matlab.ui.control.UIAxes
        GPSAltitudeTab                  matlab.ui.container.Tab
        GPS_Altitude_Graph              matlab.ui.control.UIAxes
        PressureTab                     matlab.ui.container.Tab
        Pressure_Graph                  matlab.ui.control.UIAxes
        TemperatureTab                  matlab.ui.container.Tab
        Temperature_Graph               matlab.ui.control.UIAxes
        AccelerationxTab                matlab.ui.container.Tab
        Acceleration_x_Graph            matlab.ui.control.UIAxes
        AccelerationyTab                matlab.ui.container.Tab
        Acceleration_y_Graph            matlab.ui.control.UIAxes
        AccelerationzTab                matlab.ui.container.Tab
        Acceleration_z_Graph            matlab.ui.control.UIAxes
        HeadingTab                      matlab.ui.container.Tab
        Heading_Graph                   matlab.ui.control.UIAxes
        AngularVelocityTab              matlab.ui.container.Tab
        Angular_Velocity_Graph          matlab.ui.control.UIAxes
        AngularAccelerationTab          matlab.ui.container.Tab
        Angular_Acceleration_Graph      matlab.ui.control.UIAxes
        Hardware_Status_Graphs          matlab.ui.container.TabGroup
        BattTempTab                     matlab.ui.container.Tab
        Batt_Temp_Graph                 matlab.ui.control.UIAxes
        MotorTempTab                    matlab.ui.container.Tab
        Motor_Temp_Graph                matlab.ui.control.UIAxes
        BoardTempTab                    matlab.ui.container.Tab
        Board_Temp_Graph                matlab.ui.control.UIAxes
        VoltageTab                      matlab.ui.container.Tab
        Voltage_Graph                   matlab.ui.control.UIAxes
        CurrentTab                      matlab.ui.container.Tab
        Current_Graph                   matlab.ui.control.UIAxes
        TabGroup_3                      matlab.ui.container.TabGroup
        GPSLongitudeTab                 matlab.ui.container.Tab
        UIAxes_10                       matlab.ui.control.UIAxes
        GPSLatitudeTab                  matlab.ui.container.Tab
        UIAxes_11                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        
        Master_Timer % Timer Object for Master Update
        a
        MET_seconds = 0
        MET_minutes = 0
        MET_hours = 0
        MET_run = 0
        UTC_seconds
        UTC_minutes
        UTC_hours
        deploy_chutes = 0
        abort = 0
        stop_reading = 1
        stop_program = 0
        t = [0]
        v
        B = 3950
        R1 = 10000
        R2
        TemperatureK
        TemperatureC
        TemperatureCList = []
        Batt_Temp_Line
    end
    
    methods (Access = private)
        
        function Master_Update(app,~,~)
            
            % UTC Update
            
            format shortg
            c = clock;
            app.UTC_seconds = ceil(c(1,6));
            app.UTC_minutes = c(1,5);
            app.UTC_hours = c(1,4);
            
            if app.UTC_seconds == 60
                app.UTC_minutes = app.UTC_minutes + 1;
                app.UTC_seconds = app.UTC_seconds - 60;
            end
            
            if app.UTC_minutes == 60
                app.UTC_minutes = app.UTC_minutes - 60;
            end               

            app.UTC_Seconds_Field.Value = num2str(app.UTC_seconds,'%02.f');
            app.UTC_Minutes_Field.Value = num2str(app.UTC_minutes,'%02.f');
            app.UTC_Hours_Field.Value = num2str(app.UTC_hours,'%02.f');
            
            % MET Update
            
            if app.MET_run == 1
                
                app.MET_Seconds_Field.Value = num2str(app.MET_seconds,'%02.f');
                app.MET_Minutes_Field.Value = num2str(app.MET_minutes,'%02.f');
                app.MET_Hours_Field.Value = num2str(app.MET_hours,'%02.f');
                
                app.MET_seconds = app.MET_seconds + 1;
                
                if app.MET_seconds == 60
                    app.MET_minutes = app.MET_minutes + 1;
                    app.MET_seconds = app.MET_seconds - 60;
                end
                
                if app.MET_minutes == 60
                    app.MET_minutes = app.MET_minutes - 60;
                    app.MET_hours = app.MET_hours + 1;
                end

            end
            
            % Data Read Update
            
            if app.stop_reading == 0
                
                set(app.ReceivingDataLamp, 'Color', 'g');
            
                % Packet Search Time Field
                app.PacketSearchTimeEditField.Value = 5;
                
                % Motor Temp Field
                app.MotorTempCEditField.Value = 100;
                
                % Board Temp Field
                app.BoardTempCEditField.Value = 100;
                
                % Voltage Field
                app.VoltageVEditField.Value = 10;
                
                % Current Field
                app.CurrentAEditField.Value = 0.500;
                
                % Altitude Field
                app.AltitudekmEditField.Value = 20;
                
                % Pressure Field
                app.PressurekPaEditField.Value = 100;
                
                % Temperature Field
                app.TemperatureCEditField.Value = 100;
                
                % Batt Temp Field
                app.v = readVoltage(app.a,'A0');
                app.R2 = app.R1 * (( 5 / app.v ) - 1);
                app.TemperatureK = 1 ./ (( 1 / 298.15 ) + ( 1 / app.B ) * log( app.R2 / app.R1 ));
                app.TemperatureC = app.TemperatureK - 273.15;
                
                app.BattTempCEditField.Value = app.TemperatureC;
                
                if size(app.TemperatureCList,2) == 0
                    app.TemperatureCList = [app.TemperatureC];
                else
                    app.TemperatureCList = [app.TemperatureCList app.TemperatureC];
                end
                
                % Batt Temp Graph
                %app.Batt_Temp_Line = animatedline(app.Batt_Temp_Graph,app.t,app.TemperatureC,'Color','r.');
                %addpoints(app.Batt_Temp_Line,app.t,app.TemperatureC)
                plot(app.Batt_Temp_Graph,app.t,app.TemperatureCList,'-r.')
                
                app.t = [app.t app.t(size(app.t,2))+1];
                
                if app.t(size(app.t,2)) < 20
                    app.Batt_Temp_Graph.XLim = [0 20]
                else
                    app.Batt_Temp_Graph.XLim = [app.t(size(app.t,2))-20 app.t(size(app.t,2))]
                end
                
                %app.Batt_Temp_Graph.YLim = [-Inf Inf]
                
            end
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ALP_OpeningFcn(app)
            app.a = arduino('COM3','Uno');
            
            format shortg
            c = clock;
            app.UTC_seconds = num2str(ceil(c(1,6)));
            app.UTC_minutes = num2str(c(1,5));
            app.UTC_hours = num2str(c(1,4));
            
            if app.UTC_seconds == 60
                app.UTC_seconds = 0;
            end
            
            if app.UTC_minutes == 60
                app.UTC_minutes = 0;
            end
            
            app.MET_Seconds_Field.Value = num2str(app.MET_seconds,'%02.f');
            app.MET_Minutes_Field.Value = num2str(app.MET_minutes,'%02.f');
            app.MET_Hours_Field.Value = num2str(app.MET_hours,'%02.f');
            
            app.UTC_Seconds_Field.Value = num2str(app.UTC_seconds,'%02.f');
            app.UTC_Minutes_Field.Value = num2str(app.UTC_minutes,'%02.f');
            app.UTC_Hours_Field.Value = num2str(app.UTC_hours,'%02.f');

          
            app.Master_Timer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.Master_Update);    % Specify callback function
            
            start(app.Master_Timer);


        end

        % Button pushed function: ABORT_button
        function ABORT_button_Callback(app, event)
            
            % hObject    handle to ABORT_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.abort = 1;
            
            set(app.Are_You_Sure_Menu, 'Title','Confirm ABORT')
            set(app.Are_You_Sure_Menu, 'Position', [250 660 500 132])
            set(app.Are_You_Sure_Menu,'Visible','On');
            set(app.YES_button,'Visible','On');
            set(app.NO_button,'Visible','On');
            
        end

        % Button pushed function: Deploy_Chutes_button
        function Deploy_Chutes_button_Callback(app, event)
            
            % hObject    handle to Deploy_Chutes_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.deploy_chutes = 1;
            
            set(app.Are_You_Sure_Menu, 'Title','Confirm Deploy Chutes')
            set(app.Are_You_Sure_Menu, 'Position', [250 660 500 132])
            set(app.Are_You_Sure_Menu,'Visible','On')
            set(app.YES_button,'Visible','On')
            set(app.NO_button,'Visible','On')
            
        end

        % Button pushed function: NO_button
        function NO_button_Callback(app, event)
            
            % hObject    handle to NO_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.deploy_chutes = 0;
            app.abort = 0;
            app.stop_reading = 0;
            app.stop_program = 0;
            
            set(app.YES_button,'Visible','Off');
            set(app.NO_button,'Visible','Off');
            set(app.Are_You_Sure_Menu,'Visible','Off');
            
        end

        % Button pushed function: Receive_Data_button
        function Receive_Data_button_Callback(app, event)
            
            % ALP_readinserial(1)           Uncomment this to read serial
            app.stop_reading = 0;
            
        end

        % Button pushed function: YES_button
        function YES_button_Callback(app, event)
            
            % hObject    handle to YES_button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            if app.deploy_chutes == 1
                set(app.ChutesDeployedLamp, 'Color','g');
                %ALP_sendcommands(1,0)
                app.deploy_chutes = 0;
            elseif app.abort == 1
                set(app.MissionAbortedLamp, 'Color','g');
                %ALP_sendcommands(0,1)
                app.abort = 0;
            elseif app.stop_reading == 1
                set(app.ReceivingDataLamp, 'Color','r');
                %ALP_readinserial(0)
            elseif app.stop_program == 1
                exit    
            else
                ALP_sendcommands(0,0), ALP_readinserial(1);
                
            end
            
            
            set(app.YES_button,'Visible','Off');
            set(app.NO_button,'Visible','Off');
            set(app.Are_You_Sure_Menu,'Visible','Off');
            
        end

        % Button pushed function: end_program
        function end_program_Callback(app, event)
            
            % hObject    handle to end_program (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.stop_program = 1;
            
            set(app.Are_You_Sure_Menu, 'Title','Confirm End Program')
            set(app.Are_You_Sure_Menu, 'Position', [250 528 500 132])
            set(app.Are_You_Sure_Menu,'Visible','On')
            set(app.YES_button,'Visible','On');
            set(app.NO_button,'Visible','On');
            
        end

        % Button pushed function: end_receive
        function end_receive_Callback(app, event)
            
            % hObject    handle to end_receive (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.stop_reading = 1;
            
            set(app.Are_You_Sure_Menu, 'Title','Confirm Stop Reading')
            set(app.Are_You_Sure_Menu, 'Position', [250 528 500 132])
            set(app.Are_You_Sure_Menu,'Visible','On');
            set(app.YES_button,'Visible','On');
            set(app.NO_button,'Visible','On');
            
        end

        % Button pushed function: StartClockButton
        function StartClockButtonPushed(app, event)
            
            app.MET_run = 1;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GS';
            app.ALP_Commands = uipanel(app.figure1);
            app.ALP_Commands.Title = 'ALP Commands';
            app.Receive_Data_button = uibutton(app.ALP_Commands, 'push');
            app.Receive_Data_button.ButtonPushedFcn = createCallbackFcn(app, @Receive_Data_button_Callback, true);
            app.Receive_Data_button.Text = 'Receive Data';
            app.ABORT_button = uibutton(app.ALP_Commands, 'push');
            app.ABORT_button.ButtonPushedFcn = createCallbackFcn(app, @ABORT_button_Callback, true);
            app.ABORT_button.Text = 'Abort';
            app.Deploy_Chutes_button = uibutton(app.ALP_Commands, 'push');
            app.Deploy_Chutes_button.ButtonPushedFcn = createCallbackFcn(app, @Deploy_Chutes_button_Callback, true);
            app.Deploy_Chutes_button.Text = 'Manual Deploy Chute';
            app.Confirmation_Status = uipanel(app.figure1);
            app.Confirmation_Status.Title = 'Confirmation Status';
            app.ChutesDeployedLampLabel = uilabel(app.Confirmation_Status);
            app.ChutesDeployedLampLabel.Text = 'Chutes Deployed';
            app.ChutesDeployedLamp = uilamp(app.Confirmation_Status);
            app.MissionAbortedLampLabel = uilabel(app.Confirmation_Status);
            app.MissionAbortedLampLabel.Text = 'Mission Aborted';
            app.MissionAbortedLamp = uilamp(app.Confirmation_Status);
            app.ReceivingDataLampLabel = uilabel(app.Confirmation_Status);
            app.ReceivingDataLampLabel.Text = 'Receiving Data';
            app.ReceivingDataLamp = uilamp(app.Confirmation_Status);
            app.Hardware_Status = uipanel(app.figure1);
            app.Hardware_Status.Title = 'Hardware Status';
            app.BattTempCEditFieldLabel = uilabel(app.Hardware_Status);
            app.BattTempCEditFieldLabel.Text = 'Batt Temp (˚C)';
            app.BattTempCEditField = uieditfield(app.Hardware_Status, 'numeric');
            app.BoardTempCEditFieldLabel = uilabel(app.Hardware_Status);
            app.BoardTempCEditFieldLabel.Text = 'Board Temp (˚C)';
            app.BoardTempCEditField = uieditfield(app.Hardware_Status, 'numeric');
            app.VoltageVEditFieldLabel = uilabel(app.Hardware_Status);
            app.VoltageVEditFieldLabel.Text = 'Voltage (V)';
            app.VoltageVEditField = uieditfield(app.Hardware_Status, 'numeric');
            app.CurrentAEditFieldLabel = uilabel(app.Hardware_Status);
            app.CurrentAEditFieldLabel.Text = 'Current (A)';
            app.CurrentAEditField = uieditfield(app.Hardware_Status, 'numeric');
            app.MotorTempCEditFieldLabel = uilabel(app.Hardware_Status);
            app.MotorTempCEditFieldLabel.Text = 'Motor Temp (˚C)';
            app.MotorTempCEditField = uieditfield(app.Hardware_Status, 'numeric');
            app.GUI_Commands = uipanel(app.figure1);
            app.GUI_Commands.Title = 'GUI Commands';
            app.end_receive = uibutton(app.GUI_Commands, 'push');
            app.end_receive.ButtonPushedFcn = createCallbackFcn(app, @end_receive_Callback, true);
            app.end_receive.Text = 'End Receive';
            app.end_program = uibutton(app.GUI_Commands, 'push');
            app.end_program.ButtonPushedFcn = createCallbackFcn(app, @end_program_Callback, true);
            app.end_program.Text = 'End Program';
            app.StartClockButton = uibutton(app.GUI_Commands, 'push');
            app.StartClockButton.ButtonPushedFcn = createCallbackFcn(app, @StartClockButtonPushed, true);
            app.StartClockButton.Text = 'Start Clock';
            app.Are_You_Sure_Menu = uipanel(app.figure1);
            app.Are_You_Sure_Menu.Title = 'Confirm';
            app.Are_You_Sure_Menu.Visible = 'off';
            app.NO_button = uibutton(app.Are_You_Sure_Menu, 'push');
            app.NO_button.ButtonPushedFcn = createCallbackFcn(app, @NO_button_Callback, true);
            app.NO_button.Text = 'NO';
            app.YES_button = uibutton(app.Are_You_Sure_Menu, 'push');
            app.YES_button.ButtonPushedFcn = createCallbackFcn(app, @YES_button_Callback, true);
            app.YES_button.Text = 'YES';
            app.ALP_Data = uipanel(app.figure1);
            app.ALP_Data.Title = 'ALP Data';
            app.AltitudekmEditFieldLabel = uilabel(app.ALP_Data);
            app.AltitudekmEditFieldLabel.Text = 'Altitude (km)';
            app.AltitudekmEditField = uieditfield(app.ALP_Data, 'numeric');
            app.PressurekPaEditFieldLabel = uilabel(app.ALP_Data);
            app.PressurekPaEditFieldLabel.Text = 'Pressure (kPa)';
            app.PressurekPaEditField = uieditfield(app.ALP_Data, 'numeric');
            app.TemperatureCEditFieldLabel = uilabel(app.ALP_Data);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (˚C)';
            app.TemperatureCEditField = uieditfield(app.ALP_Data, 'numeric');
            app.AccelerationxEditFieldLabel = uilabel(app.ALP_Data);
            app.AccelerationxEditFieldLabel.Text = 'Acceleration x';
            app.AccelerationxEditField = uieditfield(app.ALP_Data, 'numeric');
            app.AccelerationyEditFieldLabel = uilabel(app.ALP_Data);
            app.AccelerationyEditFieldLabel.Text = 'Acceleration y';
            app.AccelerationyEditField = uieditfield(app.ALP_Data, 'numeric');
            app.AccelerationzEditFieldLabel = uilabel(app.ALP_Data);
            app.AccelerationzEditFieldLabel.Text = 'Acceleration z';
            app.AccelerationzEditField = uieditfield(app.ALP_Data, 'numeric');
            app.TiltxEditFieldLabel = uilabel(app.ALP_Data);
            app.TiltxEditFieldLabel.Text = 'Tilt x';
            app.TiltxEditField = uieditfield(app.ALP_Data, 'numeric');
            app.TiltyEditFieldLabel = uilabel(app.ALP_Data);
            app.TiltyEditFieldLabel.Text = 'Tilt y';
            app.TiltyEditField = uieditfield(app.ALP_Data, 'numeric');
            app.TiltzEditFieldLabel = uilabel(app.ALP_Data);
            app.TiltzEditFieldLabel.Text = 'Tilt z';
            app.TiltzEditField = uieditfield(app.ALP_Data, 'numeric');
            app.GPSLongitudeEditFieldLabel = uilabel(app.ALP_Data);
            app.GPSLongitudeEditFieldLabel.Text = 'GPS Longitude';
            app.GPSLongitudeEditField = uieditfield(app.ALP_Data, 'numeric');
            app.GPSLatitudeEditFieldLabel = uilabel(app.ALP_Data);
            app.GPSLatitudeEditFieldLabel.Text = 'GPS Latitude';
            app.GPSLatitudeEditField = uieditfield(app.ALP_Data, 'numeric');
            app.GPSAltitudeEditFieldLabel = uilabel(app.ALP_Data);
            app.GPSAltitudeEditFieldLabel.Text = 'GPS Altitude';
            app.GPSAltitudeEditField = uieditfield(app.ALP_Data, 'numeric');
            app.Flight_Status = uipanel(app.figure1);
            app.Flight_Status.Title = 'Flight Status';
            app.RocketIgnitedLampLabel = uilabel(app.Flight_Status);
            app.RocketIgnitedLampLabel.Text = 'Rocket Ignited';
            app.RocketIgnitedLamp = uilamp(app.Flight_Status);
            app.AbortSuggestedLampLabel = uilabel(app.Flight_Status);
            app.AbortSuggestedLampLabel.Text = 'Abort Suggested';
            app.AbortSuggestedLamp = uilamp(app.Flight_Status);
            app.BalloonAscendingLampLabel = uilabel(app.Flight_Status);
            app.BalloonAscendingLampLabel.Text = 'Balloon Ascending';
            app.BalloonAscendingLamp = uilamp(app.Flight_Status);
            app.Software_Status = uipanel(app.figure1);
            app.Software_Status.Title = 'Software Status';
            app.PacketCountEditFieldLabel = uilabel(app.Software_Status);
            app.PacketCountEditFieldLabel.Text = 'Packet Count';
            app.PacketCountEditField = uieditfield(app.Software_Status, 'numeric');
            app.PacketSearchTimeEditFieldLabel = uilabel(app.Software_Status);
            app.PacketSearchTimeEditFieldLabel.Text = 'Packet Search Time';
            app.PacketSearchTimeEditField = uieditfield(app.Software_Status, 'numeric');
            app.PreviousPacketCountEditFieldLabel = uilabel(app.Software_Status);
            app.PreviousPacketCountEditFieldLabel.Text = 'Previous Packet Count';
            app.PreviousPacketCountEditField = uieditfield(app.Software_Status, 'numeric');
            app.SoftwareStateEditFieldLabel = uilabel(app.Software_Status);
            app.SoftwareStateEditFieldLabel.Text = 'Software State';
            app.SoftwareStateEditField = uieditfield(app.Software_Status, 'numeric');
            app.GPSSatEditFieldLabel = uilabel(app.Software_Status);
            app.GPSSatEditFieldLabel.Text = 'GPS Sat';
            app.GPSSatEditField = uieditfield(app.Software_Status, 'numeric');
            app.Clock = uipanel(app.figure1);
            app.Clock.Title = 'Clock';
            app.MET_Hours_to_Minutes = uieditfield(app.Clock, 'text');
            app.MET_Hours_to_Minutes.Value = ':';
            app.MET_Minutes_to_Seconds = uieditfield(app.Clock, 'text');
            app.MET_Minutes_to_Seconds.Value = ':';
            app.MET_Seconds_Field = uieditfield(app.Clock, 'text');
            app.MET_Minutes_Field = uieditfield(app.Clock, 'text');
            app.MET_Hours_Field = uieditfield(app.Clock, 'text');
            app.MET_Label = uitextarea(app.Clock);
            app.MET_Label.Value = {'MET'};
            app.UTC_Hours_to_Minutes = uieditfield(app.Clock, 'text');
            app.UTC_Hours_to_Minutes.Value = ':';
            app.UTC_Minutes_to_Seconds = uieditfield(app.Clock, 'text');
            app.UTC_Minutes_to_Seconds.Value = ':';
            app.UTC_Seconds_Field = uieditfield(app.Clock, 'text');
            app.UTC_Minutes_Field = uieditfield(app.Clock, 'text');
            app.UTC_Hours_Field = uieditfield(app.Clock, 'text');
            app.UTC_Label_Field = uitextarea(app.Clock);
            app.UTC_Label_Field.Value = {'UTC'};
            app.SABER_Badge = uiimage(app.figure1);
            app.SABER_Badge.ImageSource = 'SABER.png';
            app.SABER_Ground_Station_1 = uitextarea(app.figure1);
            app.SABER_Ground_Station_1.Value = {'SABER Ground Station 1'};
            app.ALP_Data_Graphs = uitabgroup(app.figure1);
            app.AltitudeTab = uitab(app.ALP_Data_Graphs);
            app.AltitudeTab.Title = 'Altitude';
            app.Altitude_Graph = uiaxes(app.AltitudeTab);
            title(app.Altitude_Graph, 'Altitude')
            xlabel(app.Altitude_Graph, 'Seconds')
            ylabel(app.Altitude_Graph, 'Kilometers')
            app.Altitude_Graph.YLim = [0 200];
            app.GPSAltitudeTab = uitab(app.ALP_Data_Graphs);
            app.GPSAltitudeTab.Title = 'GPS Altitude';
            app.GPS_Altitude_Graph = uiaxes(app.GPSAltitudeTab);
            title(app.GPS_Altitude_Graph, 'Altitude')
            xlabel(app.GPS_Altitude_Graph, 'Seconds')
            ylabel(app.GPS_Altitude_Graph, 'Kilometers')
            app.GPS_Altitude_Graph.YLim = [0 200];
            app.PressureTab = uitab(app.ALP_Data_Graphs);
            app.PressureTab.Title = 'Pressure';
            app.Pressure_Graph = uiaxes(app.PressureTab);
            title(app.Pressure_Graph, 'Pressure')
            xlabel(app.Pressure_Graph, 'Seconds')
            ylabel(app.Pressure_Graph, 'kiloPascals')
            app.Pressure_Graph.YLim = [0 150];
            app.TemperatureTab = uitab(app.ALP_Data_Graphs);
            app.TemperatureTab.Title = 'Temperature';
            app.Temperature_Graph = uiaxes(app.TemperatureTab);
            title(app.Temperature_Graph, 'Temperature')
            xlabel(app.Temperature_Graph, 'Seconds')
            ylabel(app.Temperature_Graph, 'Degrees Celsius')
            app.Temperature_Graph.YLim = [0 100];
            app.AccelerationxTab = uitab(app.ALP_Data_Graphs);
            app.AccelerationxTab.Title = 'Acceleration x';
            app.Acceleration_x_Graph = uiaxes(app.AccelerationxTab);
            title(app.Acceleration_x_Graph, 'Acceleration x')
            xlabel(app.Acceleration_x_Graph, 'Seconds')
            ylabel(app.Acceleration_x_Graph, 'm/s^2')
            app.AccelerationyTab = uitab(app.ALP_Data_Graphs);
            app.AccelerationyTab.Title = 'Acceleration y';
            app.Acceleration_y_Graph = uiaxes(app.AccelerationyTab);
            title(app.Acceleration_y_Graph, 'Acceleration y')
            xlabel(app.Acceleration_y_Graph, 'Seconds')
            ylabel(app.Acceleration_y_Graph, 'm/s^2')
            app.AccelerationzTab = uitab(app.ALP_Data_Graphs);
            app.AccelerationzTab.Title = 'Acceleration z';
            app.Acceleration_z_Graph = uiaxes(app.AccelerationzTab);
            title(app.Acceleration_z_Graph, 'Acceleration z')
            xlabel(app.Acceleration_z_Graph, 'Seconds')
            ylabel(app.Acceleration_z_Graph, 'm/s^2')
            app.HeadingTab = uitab(app.ALP_Data_Graphs);
            app.HeadingTab.Title = 'Heading';
            app.Heading_Graph = uiaxes(app.HeadingTab);
            title(app.Heading_Graph, 'Heading')
            xlabel(app.Heading_Graph, 'Seconds')
            ylabel(app.Heading_Graph, 'Degrees from North')
            app.AngularVelocityTab = uitab(app.ALP_Data_Graphs);
            app.AngularVelocityTab.Title = 'Angular Velocity';
            app.Angular_Velocity_Graph = uiaxes(app.AngularVelocityTab);
            title(app.Angular_Velocity_Graph, 'Heading')
            xlabel(app.Angular_Velocity_Graph, 'Seconds')
            ylabel(app.Angular_Velocity_Graph, 'Degrees from North')
            app.AngularAccelerationTab = uitab(app.ALP_Data_Graphs);
            app.AngularAccelerationTab.Title = 'Angular Acceleration';
            app.Angular_Acceleration_Graph = uiaxes(app.AngularAccelerationTab);
            title(app.Angular_Acceleration_Graph, 'Heading')
            xlabel(app.Angular_Acceleration_Graph, 'Seconds')
            ylabel(app.Angular_Acceleration_Graph, 'Degrees from North')
            app.Hardware_Status_Graphs = uitabgroup(app.figure1);
            app.BattTempTab = uitab(app.Hardware_Status_Graphs);
            app.BattTempTab.Title = 'Batt Temp';
            app.Batt_Temp_Graph = uiaxes(app.BattTempTab);
            title(app.Batt_Temp_Graph, 'Altitude')
            xlabel(app.Batt_Temp_Graph, 'Seconds')
            ylabel(app.Batt_Temp_Graph, 'Temperature (˚C)')
            app.MotorTempTab = uitab(app.Hardware_Status_Graphs);
            app.MotorTempTab.Title = 'Motor Temp';
            app.Motor_Temp_Graph = uiaxes(app.MotorTempTab);
            title(app.Motor_Temp_Graph, 'Altitude')
            xlabel(app.Motor_Temp_Graph, 'Seconds')
            ylabel(app.Motor_Temp_Graph, 'Temperature (˚C)')
            app.Motor_Temp_Graph.YLim = [0 100];
            app.BoardTempTab = uitab(app.Hardware_Status_Graphs);
            app.BoardTempTab.Title = 'Board Temp';
            app.Board_Temp_Graph = uiaxes(app.BoardTempTab);
            title(app.Board_Temp_Graph, 'Altitude')
            xlabel(app.Board_Temp_Graph, 'Seconds')
            ylabel(app.Board_Temp_Graph, 'Temperature (˚C)')
            app.Board_Temp_Graph.YLim = [0 100];
            app.VoltageTab = uitab(app.Hardware_Status_Graphs);
            app.VoltageTab.Title = 'Voltage';
            app.Voltage_Graph = uiaxes(app.VoltageTab);
            title(app.Voltage_Graph, 'Altitude')
            xlabel(app.Voltage_Graph, 'Seconds')
            ylabel(app.Voltage_Graph, 'Voltage (V)')
            app.CurrentTab = uitab(app.Hardware_Status_Graphs);
            app.CurrentTab.Title = 'Current';
            app.Current_Graph = uiaxes(app.CurrentTab);
            title(app.Current_Graph, 'Altitude')
            xlabel(app.Current_Graph, 'Seconds')
            ylabel(app.Current_Graph, 'Current (A)')
            app.TabGroup_3 = uitabgroup(app.figure1);
            app.GPSLongitudeTab = uitab(app.TabGroup_3);
            app.GPSLongitudeTab.Title = 'GPS Longitude';
            app.UIAxes_10 = uiaxes(app.GPSLongitudeTab);
            title(app.UIAxes_10, 'Heading')
            xlabel(app.UIAxes_10, 'Seconds')
            ylabel(app.UIAxes_10, 'Degrees')
            app.UIAxes_10.YLim = [-180 180];
            app.UIAxes_10.YTick = [-180 -120 -60 0 60 120 180];
            app.UIAxes_10.YTickLabel = {'-180'; '-120'; '-60'; '0'; '60'; '120'; '180'};
            app.GPSLatitudeTab = uitab(app.TabGroup_3);
            app.GPSLatitudeTab.Title = 'GPS Latitude';
            app.UIAxes_11 = uiaxes(app.GPSLatitudeTab);
            title(app.UIAxes_11, 'Heading')
            xlabel(app.UIAxes_11, 'Seconds')
            ylabel(app.UIAxes_11, 'Degrees')
            app.UIAxes_11.YLim = [-90 90];
            app.UIAxes_11.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes_11.YTick = [-90 -60 -30 0 30 60 90];
            app.UIAxes_11.YTickLabel = {'-90'; '-60'; '-30'; '0'; '30'; '60'; '90'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ALP_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @ALP_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end