classdef ALP_App_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        SABER_Banner                  matlab.ui.control.EditField
        UTC_Background                matlab.ui.control.EditField
        MET_Background                matlab.ui.control.EditField
        UTC_Seconds_Field             matlab.ui.control.Label
        UTC_Minutes_to_Seconds        matlab.ui.control.Label
        UTC_Minutes_Field             matlab.ui.control.Label
        UTC_Hours_to_Minutes          matlab.ui.control.Label
        UTC_Hours_Field               matlab.ui.control.Label
        UTC_Label                     matlab.ui.control.Label
        MET_Seconds_Field             matlab.ui.control.Label
        MET_Minutes_to_Seconds        matlab.ui.control.Label
        MET_Minutes_Field             matlab.ui.control.Label
        MET_Hours_to_Minutes          matlab.ui.control.Label
        MET_Hours_Field               matlab.ui.control.Label
        MET_Label                     matlab.ui.control.Label
        StatusPanel                   matlab.ui.container.Panel
        SoftwareStateGaugeLabel       matlab.ui.control.Label
        SoftwareStateGauge            matlab.ui.control.LinearGauge
        RocketAltitudeGaugeLabel      matlab.ui.control.Label
        RocketAltitudeGauge           matlab.ui.control.LinearGauge
        ALPStatusTextAreaLabel        matlab.ui.control.Label
        ALPStatusTextArea             matlab.ui.control.TextArea
        RocketStatusTextAreaLabel     matlab.ui.control.Label
        RocketStatusTextArea          matlab.ui.control.TextArea
        AbortStatusTextAreaLabel      matlab.ui.control.Label
        AbortStatusTextArea           matlab.ui.control.TextArea
        ErrorMessagesTextAreaLabel    matlab.ui.control.Label
        ErrorMessagesTextArea         matlab.ui.control.TextArea
        CommandsPanel                 matlab.ui.container.Panel
        StartMissionClockButton       matlab.ui.control.Button
        InititializeMissionButton     matlab.ui.control.Button
        GPSSwitchLabel                matlab.ui.control.Label
        GPSSwitch                     matlab.ui.control.Switch
        AccelerometerSwitchLabel      matlab.ui.control.Label
        AccelerometerSwitch           matlab.ui.control.Switch
        ReceiveDataSwitchLabel        matlab.ui.control.Label
        ReceiveDataSwitch             matlab.ui.control.Switch
        RocketIgnitionOverrideButton  matlab.ui.control.Button
        ReleaseALPClampButton         matlab.ui.control.Button
        TemperatureSensorSwitchLabel  matlab.ui.control.Label
        TemperatureSensorSwitch       matlab.ui.control.Switch
        PressureSensorSwitchLabel     matlab.ui.control.Label
        PressureSensorSwitch          matlab.ui.control.Switch
        AltitudeSensorSwitch_2Label   matlab.ui.control.Label
        AltitudeSensorSwitch_2        matlab.ui.control.Switch
        MainChuteDeployButton         matlab.ui.control.Button
        DrogueChuteDeployButton       matlab.ui.control.Button
        Switch_7Label                 matlab.ui.control.Label
        Switch_7                      matlab.ui.control.Switch
        Switch_8Label                 matlab.ui.control.Label
        Switch_8                      matlab.ui.control.Switch
        GSAntennaeSwitchLabel         matlab.ui.control.Label
        GSAntennaeSwitch              matlab.ui.control.Switch
        EndProgramButton              matlab.ui.control.Button
        InitializeAbortButton         matlab.ui.control.Button
        Switch_10Label                matlab.ui.control.Label
        Switch_10                     matlab.ui.control.Switch
        Switch_11Label                matlab.ui.control.Label
        Switch_11                     matlab.ui.control.Switch
        Switch_12Label                matlab.ui.control.Label
        Switch_12                     matlab.ui.control.Switch
    end

    
    properties (Access = private)
        Master_Timer % Timer Object for Master Update
        a
        MET_seconds = 0
        MET_minutes = 0
        MET_hours = 0
        MET_run = 0                 % When =1, MET is running
        UTC_seconds
        UTC_minutes
        UTC_hours
        Rocket_Software_State = 0   % Property for rocket software state
        ALP_Software_State = 0      % Property for ALP software state
        Abort_Software_State = 0    % Property for abort software state
        Error_Software_State = 0    % Property for error software state
        Rocket_Status_Flag = 1      % When =1, the Rocket Status Text Area will be updated in Master Update
        ALP_Status_Flag = 1         % When =1, the ALP Status Text Area will be updated in Master Update
        Abort_Status_Flag = 1       % When =1, the Abort Status Text Area will be updated in Master Update
        Error_Messages_Flag = 1     % When =1, the Error Messages Text Area will be updated in Master Update
    end
    methods (Access = private)
        
        function Master_Update(app,~,~)
            tic
            % UTC Update
            
            format shortg
            c = clock;
            app.UTC_seconds = ceil(c(1,6));
            app.UTC_minutes = c(1,5);
            app.UTC_hours = c(1,4)+6;
            
            if app.UTC_seconds == 60
                app.UTC_minutes = app.UTC_minutes + 1;
                app.UTC_seconds = app.UTC_seconds - 60;
            end
            
            if app.UTC_minutes == 60
                app.UTC_minutes = app.UTC_minutes - 60;
            end    
            
            if app.UTC_hours > 24
                app.UTC_hours = app.UTC_hours - 24;
            end
            
            app.UTC_Seconds_Field.Text = num2str(app.UTC_seconds,'%02.f');
            app.UTC_Minutes_Field.Text = num2str(app.UTC_minutes,'%02.f');
            app.UTC_Hours_Field.Text = num2str(app.UTC_hours,'%02.f');


            % MET Update
            
            if app.MET_run == 1
                
                app.Rocket_Software_State = app.Rocket_Software_State + 0.25;
                app.Rocket_Status_Flag = app.Rocket_Status_Flag + 0.25;
                app.ALP_Software_State = app.ALP_Software_State + 0.5;
                app.ALP_Status_Flag = app.ALP_Status_Flag + 0.5;
                app.Error_Software_State = app.Error_Software_State + 0.5;
                app.Error_Messages_Flag = app.Error_Messages_Flag + 0.5;
                
                app.MET_Seconds_Field.Text = num2str(app.MET_seconds,'%02.f');
                app.MET_Minutes_Field.Text = num2str(app.MET_minutes,'%02.f');
                app.MET_Hours_Field.Text = num2str(app.MET_hours,'%02.f');
                
                app.MET_seconds = app.MET_seconds + 1;
                
                if app.MET_seconds == 60
                    app.MET_minutes = app.MET_minutes + 1;
                    app.MET_seconds = app.MET_seconds - 60;
                end
                
                if app.MET_minutes == 60
                    app.MET_minutes = app.MET_minutes - 60;
                    app.MET_hours = app.MET_hours + 1;
                end

            end


            % Rocket Status Text Area
            
            if app.Rocket_Status_Flag == 1
                
                if app.Rocket_Software_State == 0
                    
                        app.RocketStatusTextArea.Value = 'Idle';
                        app.RocketStatusTextArea.Value = [app.RocketStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                        app.Rocket_Status_Flag = 0;
                        
                elseif app.Rocket_Software_State == 1
                    
                        app.RocketStatusTextArea.Value = [app.RocketStatusTextArea.Value(:)','Rocket Fired'];
                        app.RocketStatusTextArea.Value = [app.RocketStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                        app.Rocket_Status_Flag = 0;
                
                elseif app.Rocket_Software_State == 2
                    
                        app.RocketStatusTextArea.Value = [app.RocketStatusTextArea.Value(:)','Passed 50 km Mark'];
                        app.RocketStatusTextArea.Value = [app.RocketStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                        app.Rocket_Status_Flag = 0;
                
                elseif app.Rocket_Software_State == 3
                    
                        app.RocketStatusTextArea.Value = [app.RocketStatusTextArea.Value(:)','Passed 75 km Mark'];
                        app.RocketStatusTextArea.Value = [app.RocketStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                        app.Rocket_Status_Flag = 0;
                        
                elseif app.Rocket_Software_State == 4
                    
                        app.RocketStatusTextArea.Value = [app.RocketStatusTextArea.Value(:)','Passed Karman Line'];
                        app.RocketStatusTextArea.Value = [app.RocketStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                        app.Rocket_Status_Flag = 0;
                        
                elseif app.Rocket_Software_State == 5
                    
                        app.RocketStatusTextArea.Value = [app.RocketStatusTextArea.Value(:)','Drogue Chute Deployed'];
                        app.RocketStatusTextArea.Value = [app.RocketStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                        app.Rocket_Status_Flag = 0;
                        
                elseif app.Rocket_Software_State == 6
                    
                        app.RocketStatusTextArea.Value = [app.RocketStatusTextArea.Value(:)','Main Chute Deployed'];
                        app.RocketStatusTextArea.Value = [app.RocketStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                        app.Rocket_Status_Flag = 0;
                        
                elseif app.Rocket_Software_State == 7
                    
                        app.RocketStatusTextArea.Value = [app.RocketStatusTextArea.Value(:)','Rocket Touchdown'];
                        app.RocketStatusTextArea.Value = [app.RocketStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                        app.Rocket_Status_Flag = 0;
                        
                end

            end


            % ALP Status Text Area
            
            if app.ALP_Status_Flag == 1
                
                if app.ALP_Software_State == 0
                    
                        app.ALPStatusTextArea.Value = 'Idle';
                        app.ALPStatusTextArea.Value = [app.ALPStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                        app.ALP_Status_Flag = 0;
                        
                elseif app.ALP_Software_State == 1
                    
                        app.ALPStatusTextArea.Value = [app.ALPStatusTextArea.Value(:)','Balloon Ascending'];
                        app.ALPStatusTextArea.Value = [app.ALPStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                        app.ALP_Status_Flag = 0;
                        
                elseif app.ALP_Software_State == 2
                    
                        app.ALPStatusTextArea.Value = [app.ALPStatusTextArea.Value(:)','Rocket Fired'];
                        app.ALPStatusTextArea.Value = [app.ALPStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                        app.ALP_Status_Flag = 0;
                        
                elseif app.ALP_Software_State == 3
                    
                        app.ALPStatusTextArea.Value = [app.ALPStatusTextArea.Value(:)','Drogue Chute Deployed'];
                        app.ALPStatusTextArea.Value = [app.ALPStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                        app.ALP_Status_Flag = 0;
                        
                elseif app.ALP_Software_State == 4
                    
                        app.ALPStatusTextArea.Value = [app.ALPStatusTextArea.Value(:)','Main Chute Deployed'];
                        app.ALPStatusTextArea.Value = [app.ALPStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                        app.ALP_Status_Flag = 0;
                        
                elseif app.ALP_Software_State == 5
                    
                        app.ALPStatusTextArea.Value = [app.ALPStatusTextArea.Value(:)','ALP Touchdown'];
                        app.ALPStatusTextArea.Value = [app.ALPStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                        app.ALP_Status_Flag = 0;
                        
                end

            end


            % Abort Status Text Area
            
            if app.Abort_Status_Flag == 1
                
                if app.Abort_Software_State == 0
                    
                    app.AbortStatusTextArea.Value = 'Idle';
                    app.AbortStatusTextArea.Value = [app.AbortStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                    app.Abort_Status_Flag = 0;
                    
                elseif app.Abort_Software_State == 1
                    
                    app.AbortStatusTextArea.Value = [app.AbortStatusTextArea.Value(:)','Abort Recommended - [abort criteria]'];
                    app.AbortStatusTextArea.Value = [app.AbortStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                    app.Abort_Status_Flag = 0;
                    
                elseif app.Abort_Software_State == 2
                    
                    app.AbortStatusTextArea.Value = [app.AbortStatusTextArea.Value(:)','Abort Initiated'];
                    app.AbortStatusTextArea.Value = [app.AbortStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                    app.Abort_Status_Flag = 0;
                    
                elseif app.Abort_Software_State == 3
                    
                    app.AbortStatusTextArea.Value = [app.AbortStatusTextArea.Value(:)','Mission Aborted'];
                    app.AbortStatusTextArea.Value = [app.AbortStatusTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                    app.Abort_Status_Flag = 0;
                    
                end
            end


            % Error Messages Text Area
            
            if app.Error_Messages_Flag == 1
                
                if app.Error_Software_State == 0
                    
                    app.ErrorMessagesTextArea.Value = 'Idle';
                    app.ErrorMessagesTextArea.Value = [app.ErrorMessagesTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                    app.Error_Messages_Flag = 0;
                    
                elseif app.Error_Software_State == 1
                    
                    app.ErrorMessagesTextArea.Value = [app.ErrorMessagesTextArea.Value(:)','Sensor Error - [sensor] not responding'];
                    app.ErrorMessagesTextArea.Value = [app.ErrorMessagesTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                    app.Error_Messages_Flag = 0;
                    
                elseif app.Error_Software_State == 2
                    
                    app.ErrorMessagesTextArea.Value = [app.ErrorMessagesTextArea.Value(:)','Communication Error - [device] not responding'];
                    app.ErrorMessagesTextArea.Value = [app.ErrorMessagesTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                    app.Error_Messages_Flag = 0;
                    
                elseif app.Error_Software_State == 3
                    
                    app.ErrorMessagesTextArea.Value = [app.ErrorMessagesTextArea.Value(:)','Chute Deployment Failure - [ALP/Rocket] parachute failed to deploy'];
                    app.ErrorMessagesTextArea.Value = [app.ErrorMessagesTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                    app.Error_Messages_Flag = 0;
                    
                elseif app.Error_Software_State == 4
                    
                    app.ErrorMessagesTextArea.Value = [app.ErrorMessagesTextArea.Value(:)','Rocket Ignition Failure - rocket failed to ignite'];
                    app.ErrorMessagesTextArea.Value = [app.ErrorMessagesTextArea.Value(:)',strcat('        Time:  >',num2str(app.UTC_hours,'%02.f'),':',num2str(app.UTC_minutes,'%02.f'),':',num2str(app.UTC_seconds,'%02.f'))];
                    app.Error_Messages_Flag = 0;
                    
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            format shortg
            c = clock;
            app.UTC_seconds = num2str(ceil(c(1,6)));
            app.UTC_minutes = num2str(c(1,5));
            app.UTC_hours = num2str(c(1,4))+6;
            
            if app.UTC_seconds == 60
                app.UTC_seconds = 0;
            end
            
            if app.UTC_minutes == 60
                app.UTC_minutes = 0;
            end
            
            if app.UTC_hours > 24
                app.UTC_hours = app.UTC_hours - 24;
            end
            
            app.MET_Seconds_Field.Text = num2str(app.MET_seconds,'%02.f');
            app.MET_Minutes_Field.Text = num2str(app.MET_minutes,'%02.f');
            app.MET_Hours_Field.Text = num2str(app.MET_hours,'%02.f');
            
            app.UTC_Seconds_Field.Text = num2str(app.UTC_seconds,'%02.f');
            app.UTC_Minutes_Field.Text = num2str(app.UTC_minutes,'%02.f');
            app.UTC_Hours_Field.Text = num2str(app.UTC_hours,'%02.f');

            tic
            app.Master_Timer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1-toc, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.Master_Update);    % Specify callback function
            
            start(app.Master_Timer);
            
        end

        % Button pushed function: StartMissionClockButton
        function StartMissionClockButtonPushed(app, event)
            app.MET_run = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.SABER_Banner = uieditfield(app.UIFigure, 'text');
            app.SABER_Banner.Value = 'SABER Ground Station 1';
            app.UTC_Background = uieditfield(app.UIFigure, 'text');
            app.MET_Background = uieditfield(app.UIFigure, 'text');
            app.UTC_Seconds_Field = uilabel(app.UIFigure);
            app.UTC_Seconds_Field.Text = '00';
            app.UTC_Minutes_to_Seconds = uilabel(app.UIFigure);
            app.UTC_Minutes_to_Seconds.Text = ':';
            app.UTC_Minutes_Field = uilabel(app.UIFigure);
            app.UTC_Minutes_Field.Text = '00';
            app.UTC_Hours_to_Minutes = uilabel(app.UIFigure);
            app.UTC_Hours_to_Minutes.Text = ':';
            app.UTC_Hours_Field = uilabel(app.UIFigure);
            app.UTC_Hours_Field.Text = '00';
            app.UTC_Label = uilabel(app.UIFigure);
            app.UTC_Label.Text = 'UTC';
            app.MET_Seconds_Field = uilabel(app.UIFigure);
            app.MET_Seconds_Field.Text = '00';
            app.MET_Minutes_to_Seconds = uilabel(app.UIFigure);
            app.MET_Minutes_to_Seconds.Text = ':';
            app.MET_Minutes_Field = uilabel(app.UIFigure);
            app.MET_Minutes_Field.Text = '00';
            app.MET_Hours_to_Minutes = uilabel(app.UIFigure);
            app.MET_Hours_to_Minutes.Text = ':';
            app.MET_Hours_Field = uilabel(app.UIFigure);
            app.MET_Hours_Field.Text = '00';
            app.MET_Label = uilabel(app.UIFigure);
            app.MET_Label.Text = 'MET';
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.SoftwareStateGaugeLabel = uilabel(app.StatusPanel);
            app.SoftwareStateGaugeLabel.Text = 'Software State';
            app.SoftwareStateGauge = uigauge(app.StatusPanel, 'linear');
            app.SoftwareStateGauge.Limits = [0 4];
            app.SoftwareStateGauge.MajorTicks = [0 1 2 3 4];
            app.SoftwareStateGauge.MinorTicks = [];
            app.RocketAltitudeGaugeLabel = uilabel(app.StatusPanel);
            app.RocketAltitudeGaugeLabel.Text = 'Rocket Altitude';
            app.RocketAltitudeGauge = uigauge(app.StatusPanel, 'linear');
            app.RocketAltitudeGauge.MajorTicks = [0 25 50 75 100];
            app.RocketAltitudeGauge.MinorTicks = [];
            app.ALPStatusTextAreaLabel = uilabel(app.StatusPanel);
            app.ALPStatusTextAreaLabel.Text = 'ALP Status';
            app.ALPStatusTextArea = uitextarea(app.StatusPanel);
            app.RocketStatusTextAreaLabel = uilabel(app.StatusPanel);
            app.RocketStatusTextAreaLabel.Text = 'Rocket Status';
            app.RocketStatusTextArea = uitextarea(app.StatusPanel);
            app.AbortStatusTextAreaLabel = uilabel(app.StatusPanel);
            app.AbortStatusTextAreaLabel.Text = 'Abort Status';
            app.AbortStatusTextArea = uitextarea(app.StatusPanel);
            app.ErrorMessagesTextAreaLabel = uilabel(app.StatusPanel);
            app.ErrorMessagesTextAreaLabel.Text = 'Error Messages';
            app.ErrorMessagesTextArea = uitextarea(app.StatusPanel);
            app.CommandsPanel = uipanel(app.UIFigure);
            app.CommandsPanel.Title = 'Commands';
            app.StartMissionClockButton = uibutton(app.CommandsPanel, 'push');
            app.StartMissionClockButton.ButtonPushedFcn = createCallbackFcn(app, @StartMissionClockButtonPushed, true);
            app.StartMissionClockButton.Text = 'Start Mission Clock';
            app.InititializeMissionButton = uibutton(app.CommandsPanel, 'push');
            app.InititializeMissionButton.Text = 'Inititialize Mission';
            app.GPSSwitchLabel = uilabel(app.CommandsPanel);
            app.GPSSwitchLabel.Text = 'GPS';
            app.GPSSwitch = uiswitch(app.CommandsPanel, 'slider');
            app.AccelerometerSwitchLabel = uilabel(app.CommandsPanel);
            app.AccelerometerSwitchLabel.Text = 'Accelerometer';
            app.AccelerometerSwitch = uiswitch(app.CommandsPanel, 'slider');
            app.ReceiveDataSwitchLabel = uilabel(app.CommandsPanel);
            app.ReceiveDataSwitchLabel.Text = 'Receive Data';
            app.ReceiveDataSwitch = uiswitch(app.CommandsPanel, 'slider');
            app.RocketIgnitionOverrideButton = uibutton(app.CommandsPanel, 'push');
            app.RocketIgnitionOverrideButton.Text = 'Rocket Ignition Override';
            app.ReleaseALPClampButton = uibutton(app.CommandsPanel, 'push');
            app.ReleaseALPClampButton.Text = 'Release ALP Clamp';
            app.TemperatureSensorSwitchLabel = uilabel(app.CommandsPanel);
            app.TemperatureSensorSwitchLabel.Text = 'Temperature Sensor';
            app.TemperatureSensorSwitch = uiswitch(app.CommandsPanel, 'slider');
            app.PressureSensorSwitchLabel = uilabel(app.CommandsPanel);
            app.PressureSensorSwitchLabel.Text = 'Pressure Sensor';
            app.PressureSensorSwitch = uiswitch(app.CommandsPanel, 'slider');
            app.AltitudeSensorSwitch_2Label = uilabel(app.CommandsPanel);
            app.AltitudeSensorSwitch_2Label.Text = 'Altitude Sensor';
            app.AltitudeSensorSwitch_2 = uiswitch(app.CommandsPanel, 'slider');
            app.MainChuteDeployButton = uibutton(app.CommandsPanel, 'push');
            app.MainChuteDeployButton.Text = 'Main Chute Deploy';
            app.DrogueChuteDeployButton = uibutton(app.CommandsPanel, 'push');
            app.DrogueChuteDeployButton.Text = 'Drogue Chute Deploy';
            app.Switch_7Label = uilabel(app.CommandsPanel);
            app.Switch_7Label.Text = 'Parachute Mode';
            app.Switch_7 = uiswitch(app.CommandsPanel, 'slider');
            app.Switch_7.Items = {'ALP', 'Rocket'};
            app.Switch_7.Value = 'ALP';
            app.Switch_8Label = uilabel(app.CommandsPanel);
            app.Switch_8Label.Text = 'Switch';
            app.Switch_8 = uiswitch(app.CommandsPanel, 'slider');
            app.GSAntennaeSwitchLabel = uilabel(app.CommandsPanel);
            app.GSAntennaeSwitchLabel.Text = 'GS Antennae';
            app.GSAntennaeSwitch = uiswitch(app.CommandsPanel, 'slider');
            app.EndProgramButton = uibutton(app.CommandsPanel, 'push');
            app.EndProgramButton.Text = 'End Program';
            app.InitializeAbortButton = uibutton(app.CommandsPanel, 'push');
            app.InitializeAbortButton.Text = 'Initialize Abort';
            app.Switch_10Label = uilabel(app.CommandsPanel);
            app.Switch_10Label.Text = 'Switch';
            app.Switch_10 = uiswitch(app.CommandsPanel, 'slider');
            app.Switch_11Label = uilabel(app.CommandsPanel);
            app.Switch_11Label.Text = 'Switch';
            app.Switch_11 = uiswitch(app.CommandsPanel, 'slider');
            app.Switch_12Label = uilabel(app.CommandsPanel);
            app.Switch_12Label.Text = 'Switch';
            app.Switch_12 = uiswitch(app.CommandsPanel, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ALP_App_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end