classdef ALP_App_v2_Telemetry_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Plot1Menu                matlab.ui.container.Menu
        BattTempMenu             matlab.ui.container.Menu
        MotorTempMenu            matlab.ui.container.Menu
        BoardTempMenu            matlab.ui.container.Menu
        VoltageMenu              matlab.ui.container.Menu
        CurrentMenu              matlab.ui.container.Menu
        Plot2Menu                matlab.ui.container.Menu
        AltitudeMenu             matlab.ui.container.Menu
        PressureMenu             matlab.ui.container.Menu
        TemperatureMenu          matlab.ui.container.Menu
        AccelerationxMenu        matlab.ui.container.Menu
        AccelerationyMenu        matlab.ui.container.Menu
        AccelerationzMenu        matlab.ui.container.Menu
        HeadingMenu              matlab.ui.container.Menu
        AngularVelocityMenu      matlab.ui.container.Menu
        AngularAccelerationMenu  matlab.ui.container.Menu
        Plot3Menu                matlab.ui.container.Menu
        GPSLongitudeMenu         matlab.ui.container.Menu
        GPSLatitudeMenu          matlab.ui.container.Menu
        GPSCoordinatesMenu       matlab.ui.container.Menu
        GPSAltitudeMenu          matlab.ui.container.Menu
        Image                    matlab.ui.control.Image
        SABER_Banner             matlab.ui.control.EditField
        UTC_Background           matlab.ui.control.EditField
        MET_Background           matlab.ui.control.EditField
        UTC_Seconds              matlab.ui.control.Label
        UTC_Minutes_to_Seconds   matlab.ui.control.Label
        UTC_Minutes              matlab.ui.control.Label
        UTC_Hours_to_Minutes     matlab.ui.control.Label
        UTC_Hours                matlab.ui.control.Label
        UTC_Label                matlab.ui.control.Label
        MET_Seconds              matlab.ui.control.Label
        MET_Minutes_to_Seconds   matlab.ui.control.Label
        MET_Minutes              matlab.ui.control.Label
        MET_Hours_to_Minutes     matlab.ui.control.Label
        MET_Hours                matlab.ui.control.Label
        MET_Label                matlab.ui.control.Label
        Telem1_Label             matlab.ui.control.Label
        Telem2_Label             matlab.ui.control.Label
        Telem3_Label             matlab.ui.control.Label
        Telem1_Image             matlab.ui.control.Image
        Telem2_Image             matlab.ui.control.Image
        Telem3_Image             matlab.ui.control.Image
        UTC_Label_5              matlab.ui.control.Label
        UTC_Label_6              matlab.ui.control.Label
        UTC_Label_7              matlab.ui.control.Label
        Plot2                    matlab.ui.control.UIAxes
        Plot3                    matlab.ui.control.UIAxes
        Plot1                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        plot1ind % Indicator for plot 1
        plot2ind % Indicator for plot 2
        plot3ind % Indicator for plot 3
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Menu selected function: BattTempMenu
        function BattTempMenuSelected(app, event)
            
            app.plot1ind = 'batttemp';
            app.Plot1.Title.String = 'Batt Temp';
            app.Plot1.XLabel.String = 'Time, s';
            app.Plot1.YLabel.String = 'Batt Temp, ^oC';
            app.Telem1_Label.Text = 'Batt Temp';
            app.Telem1_Image.ImageSource = 'Thermometer.png';
            
        end

        % Menu selected function: MotorTempMenu
        function MotorTempMenuSelected(app, event)
            
            app.plot1ind = 'motortemp';
            app.Plot1.Title.String = 'Motor Temp';
            app.Plot1.XLabel.String = 'Time, s';
            app.Plot1.YLabel.String = 'Motor Temp, ^oC';
            app.Telem1_Label.Text = 'Motor Temp';
            app.Telem1_Image.ImageSource = 'Thermometer.png';
            
        end

        % Menu selected function: BoardTempMenu
        function BoardTempMenuSelected(app, event)
            
            app.plot1ind = 'boardtemp';
            app.Plot1.Title.String = 'Board Temp';
            app.Plot1.XLabel.String = 'Time, s';
            app.Plot1.YLabel.String = 'Board Temp, ^oC';
            app.Telem1_Label.Text = 'Board Temp';
            app.Telem1_Image.ImageSource = 'Thermometer.png';
            
        end

        % Menu selected function: VoltageMenu
        function VoltageMenuSelected(app, event)
            
            app.plot1ind = 'voltage';
            app.Plot1.Title.String = 'Voltage';
            app.Plot1.XLabel.String = 'Time, s';
            app.Plot1.YLabel.String = 'Voltage, V';
            app.Telem1_Label.Text = 'Voltage';
            app.Telem1_Image.ImageSource = 'Electricity.png';
            
        end

        % Menu selected function: CurrentMenu
        function CurrentMenuSelected(app, event)
            
            app.plot1ind = 'current';
            app.Plot1.Title.String = 'Current';
            app.Plot1.XLabel.String = 'Time, s';
            app.Plot1.YLabel.String = 'Current, mA';
            app.Telem1_Label.Text = 'Current';
            app.Telem1_Image.ImageSource = 'Electricity.png';
            
        end

        % Menu selected function: AltitudeMenu
        function AltitudeMenuSelected(app, event)
            
            app.plot2ind = 'altitude';
            app.Plot2.Title.String = 'Altitude';
            app.Plot2.XLabel.String = 'Time, s';
            app.Plot2.YLabel.String = 'Altitude, km';
            app.Telem2_Label.Text = 'Altitude';
            app.Telem2_Image.ImageSource = 'Ruler.png';
            
        end

        % Menu selected function: PressureMenu
        function PressureMenuSelected(app, event)
            
            app.plot2ind = 'pressure';
            app.Plot2.Title.String = 'Pressure';
            app.Plot2.XLabel.String = 'Time, s';
            app.Plot2.YLabel.String = 'Pressure, bar';
            app.Telem2_Label.Text = 'Pressure';
            app.Telem2_Image.ImageSource = 'Gauge.png';
            
            
        end

        % Menu selected function: TemperatureMenu
        function TemperatureMenuSelected(app, event)
            
            app.plot2ind = 'temperature';
            app.Plot2.Title.String = 'Temperature';
            app.Plot2.XLabel.String = 'Time, s';
            app.Plot2.YLabel.String = 'Temperature, ^oC';
            app.Telem2_Label.Text = 'Temperature';
            app.Telem2_Image.ImageSource = 'Thermometer.png';
            
        end

        % Menu selected function: AccelerationxMenu
        function AccelerationxMenuSelected(app, event)
            
            app.plot2ind = 'accelerationx';
            app.Plot2.Title.String = 'Acceleration x';
            app.Plot2.XLabel.String = 'Time, s';
            app.Plot2.YLabel.String = 'Acceleration x, m/s^2';
            app.Telem2_Label.Text = 'Acceleration x';
            app.Telem2_Image.ImageSource = 'Gauge.png';
            
        end

        % Menu selected function: AccelerationyMenu
        function AccelerationyMenuSelected(app, event)
            
            app.plot2ind = 'accelerationy';
            app.Plot2.Title.String = 'Acceleration y';
            app.Plot2.XLabel.String = 'Time, s';
            app.Plot2.YLabel.String = 'Acceleration y, m/s^2';
            app.Telem2_Label.Text = 'Acceleration y';
            app.Telem2_Image.ImageSource = 'Gauge.png';
            
        end

        % Menu selected function: AccelerationzMenu
        function AccelerationzMenuSelected(app, event)
            
            app.plot2ind = 'accelerationz';
            app.Plot2.Title.String = 'Acceleration z';
            app.Plot2.XLabel.String = 'Time, s';
            app.Plot2.YLabel.String = 'Acceleration z, m/s^2';
            app.Telem2_Label.Text = 'Acceleration z';
            app.Telem2_Image.ImageSource = 'Gauge.png';
            
        end

        % Menu selected function: HeadingMenu
        function HeadingMenuSelected(app, event)
            
            app.plot2ind = 'heading';
            app.Plot2.Title.String = 'Heading';
            app.Plot2.XLabel.String = 'Time, s';
            app.Plot2.YLabel.String = 'Heading, ^o from North';
            app.Telem2_Label.Text = 'Heading';
            app.Telem2_Image.ImageSource = 'Compass.png';
            
        end

        % Menu selected function: AngularVelocityMenu
        function AngularVelocityMenuSelected(app, event)
            
            app.plot2ind = 'angularv';
            app.Plot2.Title.String = 'Angular Velocity';
            app.Plot2.XLabel.String = 'Time, s';
            app.Plot2.YLabel.String = 'Angular Velocity, rad/s';
            app.Telem2_Label.Text = 'Angular Velocity';
            app.Telem2_Image.ImageSource = 'Gauge.png';
            
        end

        % Menu selected function: AngularAccelerationMenu
        function AngularAccelerationMenuSelected(app, event)
            
            app.plot2ind = 'angulara';
            app.Plot2.Title.String = 'Angular Acceleration';
            app.Plot2.XLabel.String = 'Time, s';
            app.Plot2.YLabel.String = 'Angular Acceleration, rad/s^2';
            app.Telem2_Label.Text = 'Angular Acceleration';
            app.Telem2_Image.ImageSource = 'Gauge.png';
            
        end

        % Menu selected function: GPSLongitudeMenu
        function GPSLongitudeMenuSelected(app, event)
            
            app.plot2ind = 'gpslong';
            app.Plot3.Title.String = 'GPS Longitude';
            app.Plot3.XLabel.String = 'Time, s';
            app.Plot3.YLabel.String = 'GPS Longitude, ^o from East';
            app.Telem3_Label.Text = 'GPS Longitude';
            app.Telem3_Image.ImageSource = 'Compass.png';
            
        end

        % Menu selected function: GPSLatitudeMenu
        function GPSLatitudeMenuSelected(app, event)
            
            app.plot3ind = 'gpslat';
            app.Plot3.Title.String = 'GPS Latitude';
            app.Plot3.XLabel.String = 'Time, s';
            app.Plot3.YLabel.String = 'GPS Latitude, ^o from North';
            app.Telem3_Label.Text = 'GPS Latitude';
            app.Telem3_Image.ImageSource = 'Compass.png';
            
        end

        % Menu selected function: GPSCoordinatesMenu
        function GPSCoordinatesMenuSelected(app, event)
            
            app.plot3ind = 'gpscoord';
            app.Plot3.Title.String = 'GPS Coordinates';
            app.Plot3.XLabel.String = 'GPS Latitude, ^o from North';
            app.Plot3.YLabel.String = 'GPS Longitude, ^o from East';
            app.Telem3_Label.Text = 'GPS Coordinates';
            app.Telem3_Image.ImageSource = 'Compass.png';
            
        end

        % Menu selected function: GPSAltitudeMenu
        function GPSAltitudeMenuSelected(app, event)
            
            app.plot3ind = 'gpsaltitude';
            app.Plot3.Title.String = 'GPS Altitude';
            app.Plot3.XLabel.String = 'Time, s';
            app.Plot3.YLabel.String = 'Altitude, km';
            app.Telem3_Label.Text = 'GPS Altitude';
            app.Telem3_Image.ImageSource = 'Ruler.png';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Plot1Menu = uimenu(app.UIFigure);
            app.Plot1Menu.Text = 'Plot 1';
            app.BattTempMenu = uimenu(app.Plot1Menu);
            app.BattTempMenu.MenuSelectedFcn = createCallbackFcn(app, @BattTempMenuSelected, true);
            app.BattTempMenu.Text = 'Batt Temp';
            app.MotorTempMenu = uimenu(app.Plot1Menu);
            app.MotorTempMenu.MenuSelectedFcn = createCallbackFcn(app, @MotorTempMenuSelected, true);
            app.MotorTempMenu.Text = 'Motor Temp';
            app.BoardTempMenu = uimenu(app.Plot1Menu);
            app.BoardTempMenu.MenuSelectedFcn = createCallbackFcn(app, @BoardTempMenuSelected, true);
            app.BoardTempMenu.Text = 'Board Temp';
            app.VoltageMenu = uimenu(app.Plot1Menu);
            app.VoltageMenu.MenuSelectedFcn = createCallbackFcn(app, @VoltageMenuSelected, true);
            app.VoltageMenu.Text = 'Voltage';
            app.CurrentMenu = uimenu(app.Plot1Menu);
            app.CurrentMenu.MenuSelectedFcn = createCallbackFcn(app, @CurrentMenuSelected, true);
            app.CurrentMenu.Text = 'Current';
            app.Plot2Menu = uimenu(app.UIFigure);
            app.Plot2Menu.Text = 'Plot 2';
            app.AltitudeMenu = uimenu(app.Plot2Menu);
            app.AltitudeMenu.MenuSelectedFcn = createCallbackFcn(app, @AltitudeMenuSelected, true);
            app.AltitudeMenu.Text = 'Altitude';
            app.PressureMenu = uimenu(app.Plot2Menu);
            app.PressureMenu.MenuSelectedFcn = createCallbackFcn(app, @PressureMenuSelected, true);
            app.PressureMenu.Text = 'Pressure';
            app.TemperatureMenu = uimenu(app.Plot2Menu);
            app.TemperatureMenu.MenuSelectedFcn = createCallbackFcn(app, @TemperatureMenuSelected, true);
            app.TemperatureMenu.Text = 'Temperature';
            app.AccelerationxMenu = uimenu(app.Plot2Menu);
            app.AccelerationxMenu.MenuSelectedFcn = createCallbackFcn(app, @AccelerationxMenuSelected, true);
            app.AccelerationxMenu.Text = 'Acceleration x';
            app.AccelerationyMenu = uimenu(app.Plot2Menu);
            app.AccelerationyMenu.MenuSelectedFcn = createCallbackFcn(app, @AccelerationyMenuSelected, true);
            app.AccelerationyMenu.Text = 'Acceleration y';
            app.AccelerationzMenu = uimenu(app.Plot2Menu);
            app.AccelerationzMenu.MenuSelectedFcn = createCallbackFcn(app, @AccelerationzMenuSelected, true);
            app.AccelerationzMenu.Text = 'Acceleration z';
            app.HeadingMenu = uimenu(app.Plot2Menu);
            app.HeadingMenu.MenuSelectedFcn = createCallbackFcn(app, @HeadingMenuSelected, true);
            app.HeadingMenu.Text = 'Heading';
            app.AngularVelocityMenu = uimenu(app.Plot2Menu);
            app.AngularVelocityMenu.MenuSelectedFcn = createCallbackFcn(app, @AngularVelocityMenuSelected, true);
            app.AngularVelocityMenu.Text = 'Angular Velocity';
            app.AngularAccelerationMenu = uimenu(app.Plot2Menu);
            app.AngularAccelerationMenu.MenuSelectedFcn = createCallbackFcn(app, @AngularAccelerationMenuSelected, true);
            app.AngularAccelerationMenu.Text = 'Angular Acceleration';
            app.Plot3Menu = uimenu(app.UIFigure);
            app.Plot3Menu.Text = 'Plot 3';
            app.GPSLongitudeMenu = uimenu(app.Plot3Menu);
            app.GPSLongitudeMenu.MenuSelectedFcn = createCallbackFcn(app, @GPSLongitudeMenuSelected, true);
            app.GPSLongitudeMenu.Text = 'GPS Longitude';
            app.GPSLatitudeMenu = uimenu(app.Plot3Menu);
            app.GPSLatitudeMenu.MenuSelectedFcn = createCallbackFcn(app, @GPSLatitudeMenuSelected, true);
            app.GPSLatitudeMenu.Text = 'GPS Latitude';
            app.GPSCoordinatesMenu = uimenu(app.Plot3Menu);
            app.GPSCoordinatesMenu.MenuSelectedFcn = createCallbackFcn(app, @GPSCoordinatesMenuSelected, true);
            app.GPSCoordinatesMenu.Text = 'GPS Coordinates';
            app.GPSAltitudeMenu = uimenu(app.Plot3Menu);
            app.GPSAltitudeMenu.MenuSelectedFcn = createCallbackFcn(app, @GPSAltitudeMenuSelected, true);
            app.GPSAltitudeMenu.Text = 'GPS Altitude';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'SABER.png';
            app.SABER_Banner = uieditfield(app.UIFigure, 'text');
            app.SABER_Banner.Value = 'SABER Ground Station 1';
            app.UTC_Background = uieditfield(app.UIFigure, 'text');
            app.MET_Background = uieditfield(app.UIFigure, 'text');
            app.UTC_Seconds = uilabel(app.UIFigure);
            app.UTC_Seconds.Text = '00';
            app.UTC_Minutes_to_Seconds = uilabel(app.UIFigure);
            app.UTC_Minutes_to_Seconds.Text = ':';
            app.UTC_Minutes = uilabel(app.UIFigure);
            app.UTC_Minutes.Text = '00';
            app.UTC_Hours_to_Minutes = uilabel(app.UIFigure);
            app.UTC_Hours_to_Minutes.Text = ':';
            app.UTC_Hours = uilabel(app.UIFigure);
            app.UTC_Hours.Text = '00';
            app.UTC_Label = uilabel(app.UIFigure);
            app.UTC_Label.Text = 'UTC';
            app.MET_Seconds = uilabel(app.UIFigure);
            app.MET_Seconds.Text = '00';
            app.MET_Minutes_to_Seconds = uilabel(app.UIFigure);
            app.MET_Minutes_to_Seconds.Text = ':';
            app.MET_Minutes = uilabel(app.UIFigure);
            app.MET_Minutes.Text = '00';
            app.MET_Hours_to_Minutes = uilabel(app.UIFigure);
            app.MET_Hours_to_Minutes.Text = ':';
            app.MET_Hours = uilabel(app.UIFigure);
            app.MET_Hours.Text = '00';
            app.MET_Label = uilabel(app.UIFigure);
            app.MET_Label.Text = 'MET';
            app.Telem1_Label = uilabel(app.UIFigure);
            app.Telem1_Label.Text = 'Batt Temp';
            app.Telem2_Label = uilabel(app.UIFigure);
            app.Telem2_Label.Text = 'Altitude';
            app.Telem3_Label = uilabel(app.UIFigure);
            app.Telem3_Label.Text = 'GPS Longitude';
            app.Telem1_Image = uiimage(app.UIFigure);
            app.Telem1_Image.ImageSource = 'Thermometer.png';
            app.Telem2_Image = uiimage(app.UIFigure);
            app.Telem2_Image.ImageSource = 'Ruler.png';
            app.Telem3_Image = uiimage(app.UIFigure);
            app.Telem3_Image.ImageSource = 'Compass.png';
            app.UTC_Label_5 = uilabel(app.UIFigure);
            app.UTC_Label_5.Text = '106 °W';
            app.UTC_Label_6 = uilabel(app.UIFigure);
            app.UTC_Label_6.Text = '0 km';
            app.UTC_Label_7 = uilabel(app.UIFigure);
            app.UTC_Label_7.Text = '40 °C ';
            app.Plot2 = uiaxes(app.UIFigure);
            title(app.Plot2, 'Altitude')
            xlabel(app.Plot2, 'Time, s')
            ylabel(app.Plot2, 'Altitude, km')
            app.Plot3 = uiaxes(app.UIFigure);
            title(app.Plot3, 'GPS Longitude')
            xlabel(app.Plot3, 'Time, s')
            ylabel(app.Plot3, 'GPS Longitude, ^o from East')
            app.Plot1 = uiaxes(app.UIFigure);
            title(app.Plot1, 'Batt Temp')
            xlabel(app.Plot1, 'Time, s')
            ylabel(app.Plot1, 'Batt Temp, ^oC')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ALP_App_v2_Telemetry_Window

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end